classdef risolutore_grafico_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RisolutorestrutturaruotabiciclettaUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RisolutoreTab                 matlab.ui.container.Tab
        PercorsooutputfileoutputEditField  matlab.ui.control.EditField
        PercorsooutputfileoutputEditFieldLabel  matlab.ui.control.Label
        SelezionapercorsoButton_2     matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SalvaerisolviButton           matlab.ui.control.Button
        ModellocollegamentoraggicerchioneDropDown  matlab.ui.control.DropDown
        ModellocollegamentoraggicerchioneDropDownLabel  matlab.ui.control.Label
        PercorsooutputfileinputEditField  matlab.ui.control.EditField
        PercorsooutputfileinputEditFieldLabel  matlab.ui.control.Label
        SelezionapercorsoButton       matlab.ui.control.Button
        NumeroraggiEditField          matlab.ui.control.NumericEditField
        NumeroraggiEditFieldLabel     matlab.ui.control.Label
        NumerosottoelementiEditField  matlab.ui.control.NumericEditField
        NumerosottoelementiEditFieldLabel  matlab.ui.control.Label
        CaricoPNEditField             matlab.ui.control.NumericEditField
        CaricoPNEditFieldLabel        matlab.ui.control.Label
        DeltaTKEditField              matlab.ui.control.NumericEditField
        DeltaTKEditFieldLabel         matlab.ui.control.Label
        RaggiomEditField              matlab.ui.control.NumericEditField
        RaggiomEditFieldLabel         matlab.ui.control.Label
        ComparatoreTab                matlab.ui.container.Tab
        FileselezionatiListBox        matlab.ui.control.ListBox
        FileselezionatiListBoxLabel   matlab.ui.control.Label
        SelezionafileComparaButton    matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SalvaerisolviButton
        function SalvaerisolviButtonPushed(app, event)
            set(app.SalvaerisolviButton, 'Enable', 'off');

            fig = app.RisolutorestrutturaruotabiciclettaUIFigure;
            d = uiprogressdlg(fig, 'Title', 'Risoluzione struttura in corso...', 'Indeterminate','on');
            drawnow

            nsub = double(app.NumerosottoelementiEditField.Value);
            n_spokes = double(app.NumeroraggiEditField.Value) / 2;
            connection_type = app.ModellocollegamentoraggicerchioneDropDown.Value;
            R = double(app.RaggiomEditField.Value);
            deltaT = double(app.DeltaTKEditField.Value);
            P = double(app.CaricoPNEditField.Value) / 2;
            fixed = isequal(connection_type, 'Incastro');
            
            if nsub == 1
                mesh_type = 'coarse';
            else
                mesh_type = 'refined';
            end

            fname = strcat(sprintf('ruota_a_%draggi_', 2*n_spokes), lower(mesh_type), sprintf('_mesh_P_%d', 2*P));
            if deltaT ~=0
                fname = strcat(fname, sprintf('_T_%d', abs(deltaT)));
            end

            fname = strcat(fname, '_', lower(connection_type),'.input');
            % path = [app.PercorsooutputfileinputEditField.Value, "\"];
            path = strcat(app.PercorsooutputfileinputEditField.Value, "/");
            foup = fopen(strcat(path, fname), 'wt');
            fprintf(foup, input_generator(R, deltaT, fixed, P, nsub, n_spokes));
            fclose(foup);
            
            % [file, location] = uiputfile('*.mat','Selezionare percorso ','test.m');

            legend_name = strcat(sprintf('%d raggi ', n_spokes*2), ', ', lower(mesh_type), ' mesh ', sprintf(' P: %d', P * 2), sprintf(" "), lower(connection_type));
            if deltaT ~= 0
                legend_name = strcat(legend_name, sprintf(' T: %d', deltaT));
            end

            pathname = uigetdir('.', 'Seleziona cartella dove salvare i workspaces:');
            outputpath = app.PercorsooutputfileoutputEditField.Value;
            figure(app.RisolutorestrutturaruotabiciclettaUIFigure); % magic command :)
            [n_elem, n_nod, n_spokes, R, asservimenti, disp_vec, igl, ai_nod, n_elem_rim, ai_N_spokes, ai_N_rim, P, ai_M_rim, Table] = run_solver(path, fname, connection_type, R, nsub, P, n_spokes, outputpath);
            save(strcat(pathname, "\", fname(1:end-6), ".mat"), "n_elem", "n_nod", "n_spokes", "R", "asservimenti", "disp_vec", "igl", "ai_nod", "n_elem_rim", "ai_N_spokes", "ai_N_rim", "P", "ai_M_rim", "Table", "legend_name");
            % fig = app.UITable;
            app.UITable.Visible = true;
            % uitable(fig, 'Data', Table{:,:},'ColumnName',Table.Properties.VariableNames, 'RowName',Table.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            % uitable(fig, 'Data', Table{:,:});
            app.UITable.Data = Table{:,:};
            close(d);
            set(app.SalvaerisolviButton, 'Enable', 'on');

        end

        % Button pushed function: SelezionapercorsoButton
        function SelezionapercorsoButtonPushed(app, event)
            pathname = uigetdir;
            app.PercorsooutputfileinputEditField.Value = pathname;
            figure(app.RisolutorestrutturaruotabiciclettaUIFigure); % magic command :)
        end

        % Button pushed function: SelezionafileComparaButton
        function SelezionafileComparaButtonPushed(app, event)
            set(app.SelezionafileComparaButton, 'Enable', 'off');

            fig = app.RisolutorestrutturaruotabiciclettaUIFigure;
            d = uiprogressdlg(fig, 'Title', 'Comparazione in corso...', 'Indeterminate','on');
            drawnow

            [filenames, path] = uigetfile('.mat', 'Seleziona i file da comparare', '', 'MultiSelect','on');
            try
                app.FileselezionatiListBox.Items = filenames;
            catch
                errordlg('Per utilizzare il comparatore bisogna selezionare almeno due file', 'Selezionato un solo file');
            end

            plot_comparison(filenames, path);
            set(app.SelezionafileComparaButton, 'Enable', 'on');
        end

        % Button pushed function: SelezionapercorsoButton_2
        function SelezionapercorsoButton_2Pushed(app, event)
            pathname = uigetdir;
            app.PercorsooutputfileoutputEditField.Value = pathname;
            figure(app.RisolutorestrutturaruotabiciclettaUIFigure); % magic command :)
        end

        % Close request function: 
        % RisolutorestrutturaruotabiciclettaUIFigure
        function RisolutorestrutturaruotabiciclettaUIFigureCloseRequest(app, event)
            fclose('all');
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RisolutorestrutturaruotabiciclettaUIFigure = uifigure('Visible', 'off');
            app.RisolutorestrutturaruotabiciclettaUIFigure.Name = 'Risolutore struttura ruota bicicletta';
            app.RisolutorestrutturaruotabiciclettaUIFigure.CloseRequestFcn = createCallbackFcn(app, @RisolutorestrutturaruotabiciclettaUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.RisolutorestrutturaruotabiciclettaUIFigure);
            app.RisolutoreTab = uitab(app.TabGroup);
            app.RisolutoreTab.Title = 'Risolutore';
            app.RaggiomEditFieldLabel = uilabel(app.RisolutoreTab);
            app.RaggiomEditFieldLabel.Text = 'Raggio [m]';
            app.RaggiomEditField = uieditfield(app.RisolutoreTab, 'numeric');
            app.RaggiomEditField.Placeholder = '0.326';
            app.RaggiomEditField.Value = 0.326;
            app.DeltaTKEditFieldLabel = uilabel(app.RisolutoreTab);
            app.DeltaTKEditFieldLabel.Text = 'Delta T [K]';
            app.DeltaTKEditField = uieditfield(app.RisolutoreTab, 'numeric');
            app.DeltaTKEditField.Value = -83.33;
            app.CaricoPNEditFieldLabel = uilabel(app.RisolutoreTab);
            app.CaricoPNEditFieldLabel.Text = 'Carico P [N]';
            app.CaricoPNEditField = uieditfield(app.RisolutoreTab, 'numeric');
            app.CaricoPNEditField.Placeholder = '225';
            app.CaricoPNEditField.Value = 450;
            app.NumerosottoelementiEditFieldLabel = uilabel(app.RisolutoreTab);
            app.NumerosottoelementiEditFieldLabel.Text = 'Numero sottoelementi';
            app.NumerosottoelementiEditField = uieditfield(app.RisolutoreTab, 'numeric');
            app.NumerosottoelementiEditField.Value = 1;
            app.NumeroraggiEditFieldLabel = uilabel(app.RisolutoreTab);
            app.NumeroraggiEditFieldLabel.Text = 'Numero raggi';
            app.NumeroraggiEditField = uieditfield(app.RisolutoreTab, 'numeric');
            app.NumeroraggiEditField.Limits = [0 Inf];
            app.NumeroraggiEditField.Value = 36;
            app.SelezionapercorsoButton = uibutton(app.RisolutoreTab, 'push');
            app.SelezionapercorsoButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionapercorsoButtonPushed, true);
            app.SelezionapercorsoButton.Text = 'Seleziona percorso';
            app.PercorsooutputfileinputEditFieldLabel = uilabel(app.RisolutoreTab);
            app.PercorsooutputfileinputEditFieldLabel.Text = 'Percorso output (file . input)';
            app.PercorsooutputfileinputEditField = uieditfield(app.RisolutoreTab, 'text');
            app.PercorsooutputfileinputEditField.Value = './Telai-MdS/input';
            app.ModellocollegamentoraggicerchioneDropDownLabel = uilabel(app.RisolutoreTab);
            app.ModellocollegamentoraggicerchioneDropDownLabel.Text = 'Modello collegamento raggi-cerchione';
            app.ModellocollegamentoraggicerchioneDropDown = uidropdown(app.RisolutoreTab);
            app.ModellocollegamentoraggicerchioneDropDown.Items = {'Incastro', 'Cerniera'};
            app.ModellocollegamentoraggicerchioneDropDown.Value = 'Incastro';
            app.SalvaerisolviButton = uibutton(app.RisolutoreTab, 'push');
            app.SalvaerisolviButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaerisolviButtonPushed, true);
            app.SalvaerisolviButton.Text = 'Salva e risolvi';
            app.UITable = uitable(app.RisolutoreTab);
            app.UITable.ColumnName = {'Vincolo'; 'Rx_N'; 'Ry_N'; 'M_Nm'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.SelezionapercorsoButton_2 = uibutton(app.RisolutoreTab, 'push');
            app.SelezionapercorsoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelezionapercorsoButton_2Pushed, true);
            app.SelezionapercorsoButton_2.Text = 'Seleziona percorso';
            app.PercorsooutputfileoutputEditFieldLabel = uilabel(app.RisolutoreTab);
            app.PercorsooutputfileoutputEditFieldLabel.Text = 'Percorso output (file . output)';
            app.PercorsooutputfileoutputEditField = uieditfield(app.RisolutoreTab, 'text');
            app.PercorsooutputfileoutputEditField.Value = './Telai-MdS/output';
            app.ComparatoreTab = uitab(app.TabGroup);
            app.ComparatoreTab.Title = 'Comparatore';
            app.SelezionafileComparaButton = uibutton(app.ComparatoreTab, 'push');
            app.SelezionafileComparaButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionafileComparaButtonPushed, true);
            app.SelezionafileComparaButton.Text = 'Seleziona file & Compara';
            app.FileselezionatiListBoxLabel = uilabel(app.ComparatoreTab);
            app.FileselezionatiListBoxLabel.Text = 'File selezionati';
            app.FileselezionatiListBox = uilistbox(app.ComparatoreTab);
            app.FileselezionatiListBox.Items = {''};
            app.FileselezionatiListBox.Value = '';
            % Show the figure after all components are created
            app.RisolutorestrutturaruotabiciclettaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = risolutore_grafico_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RisolutorestrutturaruotabiciclettaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RisolutorestrutturaruotabiciclettaUIFigure)
        end
    end
end